classdef GUI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResetTimesButton                matlab.ui.control.Button
        InsertTimeButton                matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        Timesofstimulation              matlab.ui.control.EditField
        TimesofstimulationhhmmssEditFieldLabel  matlab.ui.control.Label
        FiletoreadDropDown              matlab.ui.control.DropDown
        FiletoreadDropDownLabel         matlab.ui.control.Label
        NeuroKitProcessingPanel         matlab.ui.container.Panel
        SCRMethod                       matlab.ui.control.DropDown
        FindSCRMethodLabel              matlab.ui.control.Label
        DecompMethod                    matlab.ui.control.DropDown
        TonicandPhasicDecompositionMethodDropDownLabel  matlab.ui.control.Label
        PreProcessingPanel              matlab.ui.container.Panel
        EnterFilterOrderEditField       matlab.ui.control.NumericEditField
        EnterFilterOrderEditFieldLabel  matlab.ui.control.Label
        SelectcutoffFrequencySlider     matlab.ui.control.Slider
        SelectcutoffFrequencySliderLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs=3;
        DirPath = ['C:\Users\alonz\OneDrive - Technion\תואר\סמסטר 6\פרויקט\' ...
                'project - Stress Detection with a Smart Ring\Ring Samples\CSV'];   
        Time
        secsFromStart
        raw
        acc
        st
        ii
        MM
        SCR
        stimulationTimes = NaT(1);


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            filesList = dir(app.DirPath);
            filesNames = strings;
            
            for jj = 1:(length(filesList)-2)
                filesNames(end+1,:) = filesList(jj+2).name;
            end
            filesNames = sort(filesNames(2:end));
            app.FiletoreadDropDown.Items = filesNames;
            
            % to load data on startup:
            FiletoreadDropDownValueChanged(app)


        end

        % Value changed function: FiletoreadDropDown
        function FiletoreadDropDownValueChanged(app, event)
            fileName = app.FiletoreadDropDown.Value;
            filePath = append(app.DirPath,append('\',fileName));
            T = readtable(filePath);
            app.Time = timeofday(T.date_time);
            app.secsFromStart = T.time_sec_;
            app.raw = T.raw;
            app.acc = [T.ax, T.ay, T.az];
            app.st = T.st;
            app.ii = T.ii;
            app.MM = T.mm;
            app.SCR = T.scr;
            app.stimulationTimes = NaT(1);
            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %preprocess:
            order = app.EnterFilterOrderEditField.Value;
            cutoffFreq = app.SelectcutoffFrequencySlider.Value;
            preProccesedData = preprocess(app.raw,app.fs,order,cutoffFreq);

            %extract tonic and phasic components:
            phasicMethod = app.DecompMethod.Value;
            tonic = pyrunfile('neurokitMatlab.py', "tonic", ...
                eda_signal = preProccesedData, phasicMethod = phasicMethod, peakMethod='');
            phasic = pyrunfile('neurokitMatlab.py', "phasic", ...
                eda_signal = preProccesedData, phasicMethod = phasicMethod, peakMethod='');
            % transfer from pylist to matlab array:
            tonic = double(tonic);
            phasic = double(phasic);

            %find peaks:
             peakMethod = app.SCRMethod.Value;
             EDA_peaks = pyrunfile('neurokitMatlab.py', "EDA_peaks", ...
                eda_signal = phasic, phasicMethod = '',peakMethod=peakMethod);

            tEDA_peaks = app.Time(double(EDA_peaks)+1);
            ampEDA_peaks = phasic(double(EDA_peaks)+1);
            %plot:
            plot(app.UIAxes,app.Time,preProccesedData,app.Time,phasic,app.Time,tonic,tEDA_peaks,ampEDA_peaks,'o');
            grid(app.UIAxes,'on');
            if ~isempty(app.stimulationTimes)
                tStim = timeofday(datetime(app.stimulationTimes,'InputFormat','HH:mm:ss'));
                xline(app.UIAxes,tStim);
            end
            legend(app.UIAxes,'Preproccesed Data', 'phasic','tonic');

        end

        % Button pushed function: InsertTimeButton
        function InsertTimeButtonPushed(app, event)
            stimulationTime = datetime(app.Timesofstimulation.Value);
            app.stimulationTimes(end+1) = stimulationTime;
            app.Timesofstimulation.Value = '';


        end

        % Button pushed function: ResetTimesButton
        function ResetTimesButtonPushed(app, event)
            app.stimulationTimes = NaT(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PreProcessingPanel = uipanel(app.UIFigure);
            app.PreProcessingPanel.Title = 'PreProcessing';
            app.SelectcutoffFrequencySliderLabel = uilabel(app.PreProcessingPanel);
            app.SelectcutoffFrequencySliderLabel.Text = 'Select cutoff Frequency';
            app.SelectcutoffFrequencySlider = uislider(app.PreProcessingPanel);
            app.SelectcutoffFrequencySlider.Limits = [0.1 1];
            app.SelectcutoffFrequencySlider.MajorTicks = [0.1 0.5 1];
            app.SelectcutoffFrequencySlider.MinorTicks = [0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.SelectcutoffFrequencySlider.Value = 0.5;
            app.EnterFilterOrderEditFieldLabel = uilabel(app.PreProcessingPanel);
            app.EnterFilterOrderEditFieldLabel.Text = 'Enter Filter Order';
            app.EnterFilterOrderEditField = uieditfield(app.PreProcessingPanel, 'numeric');
            app.EnterFilterOrderEditField.Value = 8;
            app.NeuroKitProcessingPanel = uipanel(app.UIFigure);
            app.NeuroKitProcessingPanel.Title = 'NeuroKit Processing';
            app.TonicandPhasicDecompositionMethodDropDownLabel = uilabel(app.NeuroKitProcessingPanel);
            app.TonicandPhasicDecompositionMethodDropDownLabel.Text = 'Tonic and Phasic Decomposition Method';
            app.DecompMethod = uidropdown(app.NeuroKitProcessingPanel);
            app.DecompMethod.Items = {'Smooth Median', 'High Pass', 'Convex'};
            app.DecompMethod.Value = 'Smooth Median';
            app.FindSCRMethodLabel = uilabel(app.NeuroKitProcessingPanel);
            app.FindSCRMethodLabel.Text = 'Find SCR Method';
            app.SCRMethod = uidropdown(app.NeuroKitProcessingPanel);
            app.SCRMethod.Items = {'NeuroKit', 'Kim2004', 'Nabian2018', 'Gamboa2008', 'Vanhalem2020'};
            app.SCRMethod.Value = 'NeuroKit';
            app.FiletoreadDropDownLabel = uilabel(app.UIFigure);
            app.FiletoreadDropDownLabel.Text = 'File to read';
            app.FiletoreadDropDown = uidropdown(app.UIFigure);
            app.FiletoreadDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletoreadDropDownValueChanged, true);
            app.TimesofstimulationhhmmssEditFieldLabel = uilabel(app.UIFigure);
            app.TimesofstimulationhhmmssEditFieldLabel.Text = 'Times of stimulation (hh:mm:ss)';
            app.Timesofstimulation = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.InsertTimeButton = uibutton(app.UIFigure, 'push');
            app.InsertTimeButton.ButtonPushedFcn = createCallbackFcn(app, @InsertTimeButtonPushed, true);
            app.InsertTimeButton.Text = 'Insert Time';
            app.ResetTimesButton = uibutton(app.UIFigure, 'push');
            app.ResetTimesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimesButtonPushed, true);
            app.ResetTimesButton.Text = 'Reset Times';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end